classdef BurglarAlarmApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        ClockLabel                     matlab.ui.control.Label
        GreggsSafeSecuritySystemLabel  matlab.ui.control.Label
        PINPanel                       matlab.ui.container.Panel
        SuccessLabel                   matlab.ui.control.Label
        ENTERPINEditField              matlab.ui.control.NumericEditField
        ENTERPINEditFieldLabel         matlab.ui.control.Label
        EnterButton                    matlab.ui.control.Button
        FacialPanel                    matlab.ui.container.Panel
        IsCorrectFace                  matlab.ui.control.CheckBox
        FacialRecognitionButton        matlab.ui.control.Button
        CameraAxes                     matlab.ui.control.UIAxes
        ActivateDeactivateAlarmButtonGroup  matlab.ui.container.ButtonGroup
        SettingsPanel                  matlab.ui.container.Panel
        EnterSizeButton                matlab.ui.control.StateButton
        FontSizeSpinner                matlab.ui.control.Spinner
        FontSizeSpinnerLabel           matlab.ui.control.Label
        UpdateListButton               matlab.ui.control.Button
        AlarmList                      matlab.ui.control.ListBox
        BurglarAlarmActivationsListBoxLabel  matlab.ui.control.Label
        DeactivateButton               matlab.ui.control.ToggleButton
        ActivateButton                 matlab.ui.control.ToggleButton
        AlarmStatusLabel               matlab.ui.control.Label
    end

    
    properties (Access = private)
        clockTimer % Description

        serialObj      % Serial object for Arduino
        serialTimer    % Timer to poll Arduino

        cam  % Define webcam object as a property
        previewTimer  % Define the timer object as a property
        frameSize % Description
    end
    
    methods (Access = private)        

        function results = updateClock(app)
            currentTime = datestr(now, 'dd-mmm-yyyy HH:MM:SS');
            app.ClockLabel.Text = currentTime;
        end
        
        
        function checkSerial(app)
            try
                % Only check if data is available
                if app.serialObj.NumBytesAvailable > 0
                    % Read the incoming line
                    flush(app.serialObj);
                    pause(0.5);

                    msg = readline(app.serialObj);
                    msg = strtrim(msg);  % Remove whitespace and newline
                    
                    % Check for known messages
                    switch msg
                        case "ALARM_TRIGGERED"
                            app.AlarmStatusLabel.Text = "Alarm has been triggered!";
                        case "ALARM_ACTIVATED"
                            app.AlarmStatusLabel.Text = "Alarm has been activated!";
                        case "ALARM_DEACTIVATED"
                            app.AlarmStatusLabel.Text = "Alarm has been deactivated!";
                        otherwise
                            % Optional: display unknown message
                            disp("Unknown serial message: " + msg);
                    end
                end
            catch ME
                % Handle serial read errors gracefully
                disp("Serial read error: " + ME.message);
            end
        end
        
        function updateCameraFrame(app)
            if isvalid(app.cam)
                img = snapshot(app.cam);
                app.frameSize = size(img);
                image(app.CameraAxes, img);
                app.CameraAxes.Visible = 'off'; % remove axes ticks
                drawnow limitrate;
            end
        end
        
        % General function to switch panels
        function switchPanel(app, panelName)
            % List all panel names in the app (replace with actual panel names in your app)
            panelList = {'FacialPanel', 'PINPanel', 'SettingsPanel'};
            
            % Loop through each panel and hide it
            for i = 1:length(panelList)
                if strcmp(panelList{i}, panelName)
                    % Show the panel that matches the name passed
                    app.(panelList{i}).Visible = 'on';
                else
                    % Hide the other panels
                    app.(panelList{i}).Visible = 'off';
                end
            end
        end


        function streamingFaceRecognition(app, nOfEach, pauseval, option)
try
	vidObj = app.cam; %Default; You can tweak webcam properties
	%vidObj.Resolution = ('1280x1024');
catch
	beep;
	disp('Please make sure that a properly recognized webcam is connected and try again.');
	return
end

preprocessOpts.matchHistograms = true;
preprocessOpts.adjustHistograms = false;
preprocessOpts.targetForHistogramAndResize = ...
	imread('targetFaceHistogram.pgm');
preprocessOpts.targetSize = 100;
targetDirectory = fullfile(fileparts(which(mfilename)),'AutoCapturedFaces');
validateCapturedImages = true;
personNumber = 1;
dirExists = exist(targetDirectory,'dir') == 7;

if nargin < 3
    option =  0;
end

userTable = readtable('users.csv')
if isempty(userTable)
    userTable = table("Person", 1);
    writetable(userTable, "users.csv");
    writetable(table("Person", 1), "users.csv");
end

% if dirExists
%     prompt = sprintf('Would you like to:\n\nSTART OVER (Clears Existing Data!!)\nAdd Face(s) to recognition set\nor Use recognition set as is?');
%     refresh = questdlg(prompt,'Face Recognition Options','START OVER','Add Face(s)','Use as is','START OVER');
%     refreshOption = find(ismember({'START OVER','Add Face(s)','Use as is'},refresh));
% else
%     mkdir(targetDirectory);
%     refreshOption = 1;
% end
refreshOption = option;


if refreshOption == 1
	rmdir(targetDirectory,'s');  %% Remove all
	mkdir(targetDirectory)
	mkdir(fullfile(targetDirectory,filesep,['Person' num2str(1)]))
	personNumber = 1;

    writetable(table("Person", 1), "users.csv");

elseif refreshOption == 2
	tmp = dir(targetDirectory);
	fcn = @(x)ismember(x,{'.','..'});
	tmp = cellfun(fcn,{tmp.name},'UniformOutput',false);
	%personNumber = nnz(~[tmp{:}])+1;
    personNumber = userTable(end, 2).Var2(1)
	mkdir(fullfile(targetDirectory,filesep,['Person' num2str(personNumber)]))

    userTable = [userTable; {"Person", personNumber + 1}]
    writetable(userTable, "users.csv");


elseif refreshOption == 3
    personNumber = userTable(end, 2).Var2(1)

    folderToDelete = fullfile(targetDirectory, ['Person' num2str(personNumber)]);
    if exist(folderToDelete, 'dir')
        rmdir(folderToDelete, 's');
    end

    userTable(end,:) = [];
    writetable(userTable, "users.csv");

elseif refreshOption == 4
    	% Use as is--no validation of capture!
	validateCapturedImages = false;

elseif isempty(refreshOption)
	delete(vidObj)
	return
end


% fdrFig = figure('windowstyle','normal',...
% 	'name','RECORD FACE UNTIL BEEP; Press <ESCAPE> to Stop',...
% 	'units','normalized',...
% 	'menubar','none',...
% 	'position',[0.2 0.1 0.6 0.7],...
% 	'closerequestfcn',[],...
% 	'currentcharacter','0',...
% 	'keypressfcn',@checkForEscape);

% H,W of bounding box must be at least this size for a proper detection
QC.minBBSize = 30; 

% Create face detector
faceDetector = vision.CascadeObjectDetector('MergeThreshold',10);

% Number of images of each person to capture:
if nargin < 1
	nOfEach = 8;
end
%Between captured frames (allow time for movement/change):
if nargin < 2
	pauseval = 0.5;
end
% For cropping of captured faces:
bboxPad = 25;
%
captureNumber = 0;
isDone = false;
getAnother = true;

%%% START: Auto-capture/detect/train!!!
% RGBFrame = snapshot(vidObj);
% frameSize = size(RGBFrame);
% imgAx = axes('parent',fdrFig,...
% 	'units','normalized',...
% 	'position',[0.05 0.45 0.9 0.45]);
% imgHndl = imshow(RGBFrame);shg;
% app.updateCameraFrame();
disp('Esc to quit!')
if ismember(refreshOption,[1,2]) && getAnother && ~isDone
	while getAnother %&& double(get(fdrFig,'currentCharacter')) ~= 27
		% If successful, displayFrame will contain the detection box.
		% Otherwise not.
		[displayFrame, success] = capturePreprocessDetectValidateSave;
		if success
			captureNumber = captureNumber + 1;
        end
		% set(imgHndl,'CData',displayFrame);
		if captureNumber >= nOfEach
			beep;pause(0.25);beep;
			queryForNext;
		end
	end %while getAnother
end

%%% Capture is done. Now for TRAINING:
imgSet = imageSet(targetDirectory,'recursive');
% if numel(imgSet) < 2
%     close all force ;
% 	disp('streamingFaceRecognition: You must capture at least two individuals for this to work!');
%     input();
% end
if refreshOption ~= 3
	queryForNames;
end
if validateCapturedImages
	validateCaptured(imgSet);
end
sceneFeatures = trainStackedFaceDetector(imgSet);

%%% Okay, so now we should have a recognizer in place!!!
% figure(fdrFig)
% for i = 1:10
%     h(i) = text(0,0,'','Color','red','FontSize',28);
%     g(i) = plot(1,1,'r');
% end


while ~isDone %double(get(fdrFig,'currentCharacter')) ~= 27 && 
    
	bestGuess = '?';
	RGBFrame = snapshot(vidObj);
	grayFrame = rgb2gray(RGBFrame);
	bboxes = faceDetector.step(grayFrame);
    imshow(RGBFrame,'parent',imgAx);
	for jj = 1:size(bboxes,1)%#ok
		if all(bboxes(jj,3:4) >= QC.minBBSize)
			thisFace = imcrop(grayFrame,bboxes(jj,:));
			if preprocessOpts.matchHistograms
				thisFace = imhistmatch(thisFace,...
					preprocessOpts.targetForHistogramAndResize);
			end
			if preprocessOpts.adjustHistograms
				thisFace = histeq(thisFace);
			end
			thisFace = imresize(thisFace,...
				size(preprocessOpts.targetForHistogramAndResize));
			%tic;
			bestGuess = myPrediction(thisFace,sceneFeatures,numel(imgSet));
			if bestGuess == 0
				bestGuess = '?';
			else
				bestGuess = imgSet(bestGuess).Description;
			end
			%tPredict = toc
			% RGBFrame = 	insertObjectAnnotation(RGBFrame, 'rectangle', bboxes(jj,:), bestGuess,'FontSize',48);
            h(jj) = text(bboxes(jj,1), bboxes(jj,2), bestGuess,'Color','red','FontSize',20);
            h(jj).Position(1:2) = bboxes(jj,1:2) - [0,20];
            h(jj).String = bestGuess;
            
            x = bboxes(jj,1) - [0;
                                -bboxes(jj,3);
                                -bboxes(jj,3);
                                0;
                                0];
            y = bboxes(jj,2) + [0;
                                0;
                                +bboxes(jj,4);
                                +bboxes(jj,4);
                                0];
            hold on
            g(jj) = plot(x,y, 'r');
                            
		end
	end
% 	imshow(RGBFrame,'parent',imgAx);drawnow;
    drawnow;
	title([bestGuess '?'])
end %while

%%% Clean up:
delete(vidObj)
release(faceDetector)
%delete(fdrFig)


%%
% NESTED SUBFUNCTIONS

	function [displayFrame, success, imagePath] = ...
			capturePreprocessDetectValidateSave(varargin)
		% CAPTURE
		RGBFrame = snapshot(vidObj);
		% Defaults:
		displayFrame = RGBFrame;
		success = false;
		imagePath = [];
		grayFrame = rgb2gray(RGBFrame);
		% PREPROCESS
		if preprocessOpts.matchHistograms
			grayFrame = imhistmatch(grayFrame,...
				preprocessOpts.targetForHistogramAndResize); %#ok<*UNRCH>
		end
		if preprocessOpts.adjustHistograms
			grayFrame = histeq(grayFrame);
		end
		preprocessOpts.targetSize = 100;
		% DETECT
		bboxes = faceDetector.step(grayFrame);
		% VALIDATE
		if isempty(bboxes)
			return
		end
		if size(bboxes,1) > 1
			disp('Discarding multiple detections!');
			return
		end
		if any(bboxes(3:4) < QC.minBBSize)
			disp('Bounding box is too small!');
			return
		end
		% % On-the-fly QC!
		% if QC.oneMouth
		% 	mouthBox = QC.mouthDetector.step(grayFrame);
		% 	if size(mouthBox,1) ~= 1
		% 		%disp('Detected face failed MOUTH QE, and was discarded.')
		% 		return
		% 	end
		% end
		% if QC.oneNose
		% 	noseBox = QC.noseDetector.step(grayFrame);
		% 	if size(noseBox,1) ~= 1
		% 		%disp('Detected face failed NOSE QE, and was discarded.')
		% 		return
		% 	end
		% end
		% If we made it to here, the capture was successful!
		success = true;
		% Update displayFrame
% 		displayFrame = insertShape(RGBFrame, 'Rectangle', bboxes,...
% 			'linewidth',4,'color','cyan');
        
		% SAVE
        %frameSize = app.frameSize;
		% Write to personN directory
		bboxes = bboxes + [-bboxPad -bboxPad 2*bboxPad 2*bboxPad];
		% Make sure crop region is within image
		bboxes = [max(bboxes(1),1) max(bboxes(2),1) min(app.frameSize(2),bboxes(3)) min(app.frameSize(2),bboxes(4))];
		faceImg = imcrop(grayFrame,bboxes);
		minImSize = min(size(faceImg));
		thumbSize = preprocessOpts.targetSize/minImSize;
		faceImg = imresize(faceImg,thumbSize);
		% 		if matchHistograms
		% 			faceImg = imhistmatch(faceImg,targetForHistogramAndResize); %#ok<*UNRCH>
		% 		end
		%Defensive programming, since we're using floating arithmetic
		%and we need to make sure image sizes match exactly:
		sz = size(faceImg);
		if min(sz) > preprocessOpts.targetSize
			faceImg = faceImg(1:preprocessOpts.targetSize,1:preprocessOpts.targetSize);
		elseif min(sz) < preprocessOpts.targetSize
			% Not sure if we can end up here, but being safe:
			faceImg = imresize(faceImg,[preprocessOpts.targetSize,preprocessOpts.targetSize]);
		end
		imagePath = fullfile(targetDirectory,...
			['Person' num2str(personNumber)],filesep,['faceImg' num2str(captureNumber) '.png']);
		imwrite(faceImg,imagePath);
		pause(pauseval)
	end %captureAndSaveFrame

	function checkForEscape(varargin)
		if double(get(gcf,'currentcharacter'))== 27
			isDone = true;
		end
	end %checkForEscape

	function queryForNames
		prompt = {imgSet.Description};
		dlg_title = 'Specify Names';
		def = prompt;
		renameTo = inputdlg(prompt,dlg_title,1,def);
		subfolders = pathsFromImageSet(imgSet);
		for ii = 1:numel(renameTo)
			subf = subfolders{ii};
			fs = strfind(subf,filesep);
			subf(fs(end)+1:end) = '';
			subf = [subf,renameTo{ii}];%#ok
			if ~isequal(subfolders{ii},subf)
				movefile(subfolders{ii},subf);
			end
		end
		imgSet = imageSet(targetDirectory,'recursive');
	end %queryForNames

	function queryForNext
		beep
		captureAnother = questdlg(['Done capturing images for person ', num2str(personNumber), '. Capture Another?'],...
			'Capture Another?','YES','No','YES');
		if strcmp(captureAnother,'YES')
			personNumber = personNumber + 1;
			captureNumber = 0;
			mkdir(fullfile(targetDirectory,filesep,['Person' num2str(personNumber)]))
		else
			getAnother = false;
		end
	end %queryForNext

	function validateCaptured(imgSet)
		%assignin('base','imgSet',imgSet)
		for ii = 1:numel(imgSet)
			nImages = imgSet(ii).Count;
			nCols = ceil(sqrt(nImages));
			nRows = ceil(sqrt(nImages));
			[hobjpos,hobjdim] = distributeObjects(nCols,0.025,0.95,0.025);
			[vobjpos,vobjdim] = distributeObjects(nRows,0.9,0.2,0.1);
			f = togglefig('Validation',true);
			set(f,'windowstyle','normal')
			drawnow
			btn = gobjects(nImages,1);
			ax = btn;
			for jj = 1:nImages %#ok
				ax(jj) = axes('units','normalized',...
					'position',[hobjpos(rem(jj-1,nCols)+1) vobjpos(ceil(jj/nCols)) hobjdim vobjdim]);
				imshow(imread(imgSet(ii).ImageLocation{jj}));
				if jj == 2
					title(imgSet(ii).Description)
				end
				btn(jj) = uicontrol('style','checkbox',...
					'string','Discard',...
					'units','normalized',...
					'value',0,...
					'userdata',jj,...
					'Position',[hobjpos(rem(jj-1,nCols)+1) vobjpos(ceil(jj/nCols))-0.075 hobjdim 0.075]);
			end
			uicontrol('style','pushbutton',...
				'string','Continue',...
				'units','normalized',...
				'position',[0.025 0.025 0.95 0.1],...
				'callback',@registerSelection);
			uiwait(f)
		end
		
		function registerSelection(varargin)
			togglefig('Validation')
			btnvals = find(cell2mat(get(btn,'value')));
			if ~isempty(btnvals)
				confirmDeletion = questdlg(sprintf('Delete the selected %d image(s) from the collection of %s images?', ...
					numel(btnvals),imgSet(ii).Description),...
					'Confirm Deletion','DELETE','No','DELETE');
				if strcmp(confirmDeletion,'DELETE')
					for kk = 1:numel(btnvals)
						imgSet = removeImageFromImageSet(imgSet,imgSet(ii).ImageLocation{btnvals(kk)});
						% Note: deleting images causes problems with the imageset object
						% delete(imgSet(ii).ImageLocation{btnvals(kk)});
					end
				end
			end
			delete(f);
		end %registerSelection (subfunction of validateCaptured)
	end %validateCaptured

        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.FacialPanel.Visible = true;
            app.PINPanel.Visible = false;
            app.SettingsPanel.Visible = false;
            
            app.clockTimer = timer( ...
    'ExecutionMode', 'fixedRate', ...
    'Period', 1, ...
        'TimerFcn', @(~,~) updateClock(app));
start(app.clockTimer);
            
            try
                app.serialObj = serialport("COM4", 9600);  % Replace with your COM port
                configureTerminator(app.serialObj, "LF");
        
                % Timer to poll serial data
                app.serialTimer = timer( ...
                    'ExecutionMode', 'fixedRate', ...
                    'Period', 1, ...
                    'TimerFcn', @(~,~) checkSerial(app));
                start(app.serialTimer);
            catch err
                app.AlarmStatusLabel.Text = "Can't read Arduino..." + err.message;
            end
           
            % Connect to the default camera
            app.cam = webcam;
        
            % Optional: configure resolution
            % app.cam.Resolution = '640x480';
      
            app.previewTimer = timer( ...
                'ExecutionMode', 'fixedSpacing', ...
                'Period', 0.1, ...
                'TimerFcn', @(~,~) updateCameraFrame(app));

            start(app.previewTimer);
            
        end

        % Button pushed function: EnterButton
        function EnterButtonPushed(app, event)
            try
                if ~isempty(instrfind)
                    fclose(instrfind);
                    delete(instrfind);
                end
        
                pinCode = app.ENTERPINEditField.Value;  % Get PIN from user input
        
                % Open serial communication
                s = serialport("COM4", 9600);  % Update COM port if needed
                configureTerminator(s, "LF");
                flush(s);
                pause(1);  % Let Arduino boot/reset
                writeline(s, string(pinCode));        % Send PIN to Arduino
                pause(1);                     % Wait for Arduino to respond
        
                if s.NumBytesAvailable > 0
                    response = readline(s);
                    response = strip(response);        
                    if strcmp(string(response), "ACCESS GRANTED")
                        app.SuccessLabel.Text = "✅ Access Granted!";

                        app.AlarmStatusLabel.Visible = 'on';     % Show the menu button
                        app.ActivateDeactivateAlarmButtonGroup.Visible = 'on'; % Another button
                        app.DeactivationMessage.Visible = 'on';
                        
                    elseif strcmp(response, "ACCESS DENIED")
                        app.SuccessLabel.Text = "❌ Incorrect PIN.";
                    else
                        app.SuccessLabel.Text = "❓ Unexpected Response: " + response;
                    end
                else
                    app.SuccessLabel.Text = "⚠️ No response from Arduino.";
                end
        
                clear s
            catch err
                app.SuccessLabel.Text = "❌ Error: " + err.message;
            end
        end

        % Value changed function: EnterSizeButton
        function EnterSizeButtonValueChanged(app, event)
            value = app.FontSizeSpinner.Value;
            
            app.SuccessLabel.FontSize = value;
        end

        % Button pushed function: FacialRecognitionButton
        function FacialRecognitionButtonPushed(app, event)
            app.streamingFaceRecognition(8,0.5,2);
            try
                isCorrectFace = app.IsCorrectFace.Value;  % Get checkbox or logical input
        
                % Use the existing serial object
                if isempty(app.serialObj) || ~isvalid(app.serialObj)
                    app.SuccessLabel.Text = "❌ Serial not connected.";
                    return;
                end
        
                % Send result to Arduino
                if isCorrectFace
                    writeline(app.serialObj, "FACE_OK");  
                    switchPanel(app, 'PINPanel');
                else
                    writeline(app.serialObj, "FACE_NOTOK");
                end
        
                app.SuccessLabel.Text = "✅ Facial data sent.";
        
            catch err
                app.SuccessLabel.Text = "❌ Error: " + err.message;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.AlarmStatusLabel = uilabel(app.UIFigure);
            app.AlarmStatusLabel.Text = 'Greggs Alarm: ACTIVATED';
            app.ActivateDeactivateAlarmButtonGroup = uibuttongroup(app.UIFigure);
            app.ActivateDeactivateAlarmButtonGroup.Title = 'Activate / Deactivate Alarm';
            app.ActivateDeactivateAlarmButtonGroup.Visible = 'off';
            app.ActivateButton = uitogglebutton(app.ActivateDeactivateAlarmButtonGroup);
            app.ActivateButton.Text = 'Activate';
            app.ActivateButton.Value = true;
            app.DeactivateButton = uitogglebutton(app.ActivateDeactivateAlarmButtonGroup);
            app.DeactivateButton.Text = 'Deactivate';
            app.SettingsPanel = uipanel(app.ActivateDeactivateAlarmButtonGroup);
            app.SettingsPanel.Title = 'Panel3';
            app.BurglarAlarmActivationsListBoxLabel = uilabel(app.SettingsPanel);
            app.BurglarAlarmActivationsListBoxLabel.Text = 'Burglar Alarm Activations';
            app.AlarmList = uilistbox(app.SettingsPanel);
            app.UpdateListButton = uibutton(app.SettingsPanel, 'push');
            app.UpdateListButton.Text = 'Update List';
            app.FontSizeSpinnerLabel = uilabel(app.SettingsPanel);
            app.FontSizeSpinnerLabel.Text = 'Font Size';
            app.FontSizeSpinner = uispinner(app.SettingsPanel);
            app.FontSizeSpinner.Limits = [12 64];
            app.FontSizeSpinner.RoundFractionalValues = 'on';
            app.FontSizeSpinner.Placeholder = 'Enter Font Size';
            app.FontSizeSpinner.Value = 12;
            app.EnterSizeButton = uibutton(app.SettingsPanel, 'state');
            app.EnterSizeButton.ValueChangedFcn = createCallbackFcn(app, @EnterSizeButtonValueChanged, true);
            app.EnterSizeButton.Text = 'Enter Size';
            app.FacialPanel = uipanel(app.UIFigure);
            app.FacialPanel.Title = 'Facial Recognition Check';
            app.CameraAxes = uiaxes(app.FacialPanel);
            title(app.CameraAxes, 'Title')
            xlabel(app.CameraAxes, 'X')
            ylabel(app.CameraAxes, 'Y')
            zlabel(app.CameraAxes, 'Z')
            app.FacialRecognitionButton = uibutton(app.FacialPanel, 'push');
            app.FacialRecognitionButton.ButtonPushedFcn = createCallbackFcn(app, @FacialRecognitionButtonPushed, true);
            app.FacialRecognitionButton.Text = '"Facial Recognition"';
            app.IsCorrectFace = uicheckbox(app.FacialPanel);
            app.PINPanel = uipanel(app.UIFigure);
            app.PINPanel.Title = 'Entering PIN';
            app.EnterButton = uibutton(app.PINPanel, 'push');
            app.EnterButton.ButtonPushedFcn = createCallbackFcn(app, @EnterButtonPushed, true);
            app.EnterButton.Text = 'Enter';
            app.ENTERPINEditFieldLabel = uilabel(app.PINPanel);
            app.ENTERPINEditFieldLabel.Text = 'ENTER PIN';
            app.ENTERPINEditField = uieditfield(app.PINPanel, 'numeric');
            app.SuccessLabel = uilabel(app.PINPanel);
            app.SuccessLabel.Text = 'Success!';
            app.GreggsSafeSecuritySystemLabel = uilabel(app.UIFigure);
            app.GreggsSafeSecuritySystemLabel.Text = 'Greggs Safe Security System';
            app.ClockLabel = uilabel(app.UIFigure);
            app.ClockLabel.Text = 'Imagine Date and Time Here';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = BurglarAlarmApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end