classdef WorkingCamera_BurglarAlarmApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        FacialPanel                    matlab.ui.container.Panel
        IsCorrectFace                  matlab.ui.control.CheckBox
        FacialRecognitionButton        matlab.ui.control.Button
        CameraAxes                     matlab.ui.control.UIAxes
        ClockLabel                     matlab.ui.control.Label
        GreggsSafeSecuritySystemLabel  matlab.ui.control.Label
        PINPanel                       matlab.ui.container.Panel
        SuccessLabel                   matlab.ui.control.Label
        ENTERPINEditField              matlab.ui.control.NumericEditField
        ENTERPINEditFieldLabel         matlab.ui.control.Label
        EnterButton                    matlab.ui.control.Button
        ActivateDeactivateAlarmButtonGroup  matlab.ui.container.ButtonGroup
        SettingsPanel                  matlab.ui.container.Panel
        EnterSizeButton                matlab.ui.control.StateButton
        FontSizeSpinner                matlab.ui.control.Spinner
        FontSizeSpinnerLabel           matlab.ui.control.Label
        UpdateListButton               matlab.ui.control.Button
        AlarmList                      matlab.ui.control.ListBox
        BurglarAlarmActivationsListBoxLabel  matlab.ui.control.Label
        DeactivateButton               matlab.ui.control.ToggleButton
        ActivateButton                 matlab.ui.control.ToggleButton
        AlarmStatusLabel               matlab.ui.control.Label
    end

    
    properties (Access = private)
        clockTimer % Description

        serialObj      % Serial object for Arduino
        serialTimer    % Timer to poll Arduino

        cam  % Define webcam object as a property
        previewTimer  % Define the timer object as a property

        verified
    end
    
    methods (Access = private)        

        function results = updateClock(app)
            currentTime = datestr(now, 'dd-mmm-yyyy HH:MM:SS');
            app.ClockLabel.Text = currentTime;
        end

        function initialiseCamera(app)
            try
                app.cam = webcam;
                app.previewTimer = timer( ...
                    'ExecutionMode', 'fixedSpacing', ...
                    'Period', 0.1, ...
                    'TimerFcn', @(~,~) updateCameraFrame(app));
            
                start(app.previewTimer);
            catch e 
                disp("Serial read error: " + e);
            end

        end
        
        
        function checkSerial(app)
            try
                % Only check if data is available
                if app.serialObj.NumBytesAvailable > 0
                    % Read the incoming line
                    flush(app.serialObj);
                    pause(0.5);

                    msg = readline(app.serialObj);
                    msg = strtrim(msg);  % Remove whitespace and newline
                    
                    % Check for known messages
                    switch msg
                        case "ALARM_TRIGGERED"
                            app.AlarmStatusLabel.Text = "Alarm has been triggered!";
                        case "ALARM_ACTIVATED"
                            app.AlarmStatusLabel.Text = "Alarm has been activated!";
                        case "ALARM_DEACTIVATED"
                            app.AlarmStatusLabel.Text = "Alarm has been deactivated!";
                        otherwise
                            % Optional: display unknown message
                            disp("Unknown serial message: " + msg);
                    end
                end
            catch ME
                % Handle serial read errors gracefully
                disp("Serial read error: " + ME.message);
            end
        end
        
        function updateCameraFrame(app)
            if isvalid(app.cam)
                img = snapshot(app.cam);
                image(app.CameraAxes, img);
                app.CameraAxes.Visible = 'off'; % remove axes ticks
                drawnow limitrate;
            end
        end
        
        % General function to switch panels
        function switchPanel(app, panelName)
            % List all panel names in the app (replace with actual panel names in your app)
            panelList = {'FacialPanel', 'PINPanel', 'SettingsPanel'};
            
            % Loop through each panel and hide it
            for i = 1:length(panelList)
                if strcmp(panelList{i}, panelName)
                    % Show the panel that matches the name passed
                    app.(panelList{i}).Visible = 'on';
                else
                    % Hide the other panels
                    app.(panelList{i}).Visible = 'off';
                end
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.FacialPanel.Visible = true;
            app.PINPanel.Visible = false;
            app.SettingsPanel.Visible = false;
            
            app.clockTimer = timer( ...
    'ExecutionMode', 'fixedRate', ...
    'Period', 1, ...
        'TimerFcn', @(~,~) updateClock(app));
start(app.clockTimer);
            
            try
                app.serialObj = serialport("COM4", 9600);  % Replace with your COM port
                configureTerminator(app.serialObj, "LF");
        
                % Timer to poll serial data
                app.serialTimer = timer( ...
                    'ExecutionMode', 'fixedRate', ...
                    'Period', 1, ...
                    'TimerFcn', @(~,~) checkSerial(app));
                start(app.serialTimer);
            catch err
                app.AlarmStatusLabel.Text = "Can't read Arduino..." + err.message;
            end
           
        app.initialiseCamera();
        end

        % Button pushed function: EnterButton
        function EnterButtonPushed(app, event)
            try
                if ~isempty(instrfind)
                    fclose(instrfind);
                    delete(instrfind);
                end
        
                pinCode = app.ENTERPINEditField.Value;  % Get PIN from user input
        
                % Open serial communication
                s = serialport("COM4", 9600);  % Update COM port if needed
                configureTerminator(s, "LF");
                flush(s);
                pause(1);  % Let Arduino boot/reset
                writeline(s, string(pinCode));        % Send PIN to Arduino
                pause(1);                     % Wait for Arduino to respond
        
                if s.NumBytesAvailable > 0
                    response = readline(s);
                    response = strip(response);        
                    if strcmp(string(response), "ACCESS GRANTED")
                        app.SuccessLabel.Text = "✅ Access Granted!";

                        app.AlarmStatusLabel.Visible = 'on';     % Show the menu button
                        app.ActivateDeactivateAlarmButtonGroup.Visible = 'on'; % Another button
                        app.DeactivationMessage.Visible = 'on';
                        
                    elseif strcmp(response, "ACCESS DENIED")
                        app.SuccessLabel.Text = "❌ Incorrect PIN.";
                    else
                        app.SuccessLabel.Text = "❓ Unexpected Response: " + response;
                    end
                else
                    app.SuccessLabel.Text = "⚠️ No response from Arduino.";
                end
        
                clear s
            catch err
                app.SuccessLabel.Text = "❌ Error: " + err.message;
            end
        end

        % Value changed function: EnterSizeButton
        function EnterSizeButtonValueChanged(app, event)
            value = app.FontSizeSpinner.Value;
            
            app.SuccessLabel.FontSize = value;
        end

        % Button pushed function: FacialRecognitionButton
        function FacialRecognitionButtonPushed(app, event)
            try
                % Stop and delete the preview timer if it exists and is running
                if ~isempty(app.previewTimer) && isvalid(app.previewTimer)
                    stop(app.previewTimer);
                    delete(app.previewTimer);
                    app.previewTimer = [];
                end
                
                % Clear the webcam object if it exists
                if ~isempty(app.cam)
                    clear app.cam
                    app.cam = [];
                end

                app.verified = false;
                app.verified = streamingFaceRecognition(8, 0.5,3);
                app.initialiseCamera();

                app.SuccessLabel.Text = "Facial data sent 2 .";

                isCorrectFace = app.IsCorrectFace.Value;  % Get checkbox or logical input
        
                % Use the existing serial object
                if isempty(app.serialObj) || ~isvalid(app.serialObj)
                    app.SuccessLabel.Text = "❌ Serial not connected.";
                    return;
                end
                % Send result to Arduino
                % if isCorrectFace || (app.verified)
                %     writeline(app.serialObj, "FACE_OK");  % FIX THIS
                %     switchPanel(app, 'PINPanel');
                % else
                %     writeline(app.serialObj, "FACE_NOTOK");
                % end
                % 
                % app.SuccessLabel.Text = "✅ Facial data sent.";
        
            catch err
                app.SuccessLabel.Text = "❌ Error: " + err.message;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.AlarmStatusLabel = uilabel(app.UIFigure);
            app.AlarmStatusLabel.Text = 'Greggs Alarm: ACTIVATED';
            app.ActivateDeactivateAlarmButtonGroup = uibuttongroup(app.UIFigure);
            app.ActivateDeactivateAlarmButtonGroup.Title = 'Activate / Deactivate Alarm';
            app.ActivateDeactivateAlarmButtonGroup.Visible = 'off';
            app.ActivateButton = uitogglebutton(app.ActivateDeactivateAlarmButtonGroup);
            app.ActivateButton.Text = 'Activate';
            app.ActivateButton.Value = true;
            app.DeactivateButton = uitogglebutton(app.ActivateDeactivateAlarmButtonGroup);
            app.DeactivateButton.Text = 'Deactivate';
            app.SettingsPanel = uipanel(app.ActivateDeactivateAlarmButtonGroup);
            app.SettingsPanel.Title = 'Panel3';
            app.BurglarAlarmActivationsListBoxLabel = uilabel(app.SettingsPanel);
            app.BurglarAlarmActivationsListBoxLabel.Text = 'Burglar Alarm Activations';
            app.AlarmList = uilistbox(app.SettingsPanel);
            app.UpdateListButton = uibutton(app.SettingsPanel, 'push');
            app.UpdateListButton.Text = 'Update List';
            app.FontSizeSpinnerLabel = uilabel(app.SettingsPanel);
            app.FontSizeSpinnerLabel.Text = 'Font Size';
            app.FontSizeSpinner = uispinner(app.SettingsPanel);
            app.FontSizeSpinner.Limits = [12 64];
            app.FontSizeSpinner.RoundFractionalValues = 'on';
            app.FontSizeSpinner.Value = 12;
            app.EnterSizeButton = uibutton(app.SettingsPanel, 'state');
            app.EnterSizeButton.ValueChangedFcn = createCallbackFcn(app, @EnterSizeButtonValueChanged, true);
            app.EnterSizeButton.Text = 'Enter Size';
            app.PINPanel = uipanel(app.UIFigure);
            app.PINPanel.Title = 'Entering PIN';
            app.EnterButton = uibutton(app.PINPanel, 'push');
            app.EnterButton.ButtonPushedFcn = createCallbackFcn(app, @EnterButtonPushed, true);
            app.EnterButton.Text = 'Enter';
            app.ENTERPINEditFieldLabel = uilabel(app.PINPanel);
            app.ENTERPINEditFieldLabel.Text = 'ENTER PIN';
            app.ENTERPINEditField = uieditfield(app.PINPanel, 'numeric');
            app.SuccessLabel = uilabel(app.PINPanel);
            app.SuccessLabel.Text = 'Success!';
            app.GreggsSafeSecuritySystemLabel = uilabel(app.UIFigure);
            app.GreggsSafeSecuritySystemLabel.Text = 'Greggs Safe Security System';
            app.ClockLabel = uilabel(app.UIFigure);
            app.ClockLabel.Text = 'Imagine Date and Time Here';
            app.FacialPanel = uipanel(app.UIFigure);
            app.FacialPanel.Title = 'Facial Recognition Check';
            app.CameraAxes = uiaxes(app.FacialPanel);
            title(app.CameraAxes, 'Title')
            xlabel(app.CameraAxes, 'X')
            ylabel(app.CameraAxes, 'Y')
            zlabel(app.CameraAxes, 'Z')
            app.FacialRecognitionButton = uibutton(app.FacialPanel, 'push');
            app.FacialRecognitionButton.ButtonPushedFcn = createCallbackFcn(app, @FacialRecognitionButtonPushed, true);
            app.FacialRecognitionButton.Text = '"Facial Recognition"';
            app.IsCorrectFace = uicheckbox(app.FacialPanel);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = WorkingCamera_BurglarAlarmApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end